classdef Anderson_edit_FinalProject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        LoadButton               matlab.ui.control.Button
        ImportedImageLabel       matlab.ui.control.Label
        CurrentEditLabel         matlab.ui.control.Label
        ExportImageButton        matlab.ui.control.Button
        ContrastSliderLabel      matlab.ui.control.Label
        ContrastSlider           matlab.ui.control.Slider
        GrayscaleSwitchLabel     matlab.ui.control.Label
        GrayscaleSwitch          matlab.ui.control.Switch
        NoiseSliderLabel         matlab.ui.control.Label
        NoiseSlider              matlab.ui.control.Slider
        SharpeningSliderLabel    matlab.ui.control.Label
        SharpeningSlider         matlab.ui.control.Slider
        InvertColorsSwitchLabel  matlab.ui.control.Label
        InvertColorsSwitch       matlab.ui.control.Switch
        BrightnessSliderLabel    matlab.ui.control.Label
        BrightnessSlider         matlab.ui.control.Slider
        ImageAxes                matlab.ui.control.UIAxes
        GreenAxes                matlab.ui.control.UIAxes
        BlueAxes                 matlab.ui.control.UIAxes
        RedAxes                  matlab.ui.control.UIAxes
        ImageAxes_2              matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function updateimage(app,imagefile)
            if strcmp(imagefile,'corn.tif')
                im = imread('corn.tif', 2);
            else
                try
                    im = imread(imagefile);
                catch ME
                    uialert(app.UIFigure, ME.message, 'Image Error');
                    return;
                end            
            end 
            
            switch size(im,3)
                case 1
                    imagesc(app.ImageAxes,im);
                    imagesc(app.ImageAxes_2,im);
                    histr = histogram(app.RedAxes, im, 'FaceColor',[1 0 0],'EdgeColor', 'none');
                    histg = histogram(app.GreenAxes, im, 'FaceColor',[0 1 0],'EdgeColor', 'none');
                    histb = histogram(app.BlueAxes, im, 'FaceColor',[0 0 1],'EdgeColor', 'none');
                    
                case 3
                    imagesc(app.ImageAxes,im);
                    imagesc(app.ImageAxes_2,im);
                    histr = histogram(app.RedAxes, im(:,:,1), 'FaceColor', [1 0 0], 'EdgeColor', 'none');
                    histg = histogram(app.GreenAxes, im(:,:,2), 'FaceColor', [0 1 0], 'EdgeColor', 'none');
                    histb = histogram(app.BlueAxes, im(:,:,3), 'FaceColor', [0 0 1], 'EdgeColor', 'none');
                    
                otherwise
                    uialert(app.UIFigure, 'Image must be grayscale or truecolor.', 'Image Error');
                    return;
            end
                maxr = max(histr.BinCounts);
                maxg = max(histg.BinCounts);
                maxb = max(histb.BinCounts);
                maxcount = max([maxr maxg maxb]);
                app.RedAxes.YLim = [0 maxcount];
                app.RedAxes.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
                app.GreenAxes.YLim = [0 maxcount];
                app.GreenAxes.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
                app.BlueAxes.YLim = [0 maxcount];
                app.BlueAxes.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
         
        end
        
        function exportimage(app)
            [Filename, Filepath] = uiputfile('*.jpg','*.tif', 'Save as');
            Name = fullfile(Filepath, Filename);
            axes2 = get(app.ImageAxes_2, 'Children');
            W = get(axes2(1), 'CData');
            imwrite(W, Name, 'jpg');
        end
        
        function applycontrast(app, value)
            L = get(app.ContrastSlider, 'Value');
            im = getimage(app.ImageAxes_2);
            imgMin = double(min(im(:)));
            imgMax = double(max(im(:)));
            img = (im - imgMin) / (imgMax - imgMin) * L;
            img = my_histeq(app, im, 255);
            imh = imhandles(app.ImageAxes_2);
            img = imadjust(im, [L, 255]/255, [0, 255] / 255); 
            set(imh, 'CData', img);
            updatergbaxes(app);
        end
        
        function grayscale(app, value)
            val = get(app.GrayscaleSwitch, 'Value');            
            if (val == 1)
                axes2 = get(app.ImageAxes_2, 'Children');
                axes2 = get(app.ImageAxes_2);
                redChannel = axes2(:, :, 1);
                greenChannel = axes2(:, :, 2);
                blueChannel = axes2(:, :, 3);
                grayImage = .299*double(redChannel) + .587*double(greenChannel) + .114*double(blueChannel);
                grayImage = uint8(grayImage);
                imh = imhandles(app.ImageAxes_2);
                set(imh, 'CData', grayImage); 
            else 
                axes2 = get(app.ImageAxes_2, 'Children');
                W = get(axes2(1), 'CData');
                invertgray = uint8(255) - W;
                imh = imhandles(app.ImageAxes_2);
                set(imh, 'CData', invertgray); 
            end
            updatergbaxes(app);
        end
        
        function sharpening(app, value)
            L = get(app.SharpeningSlider, 'Value');
            A = getimage(app.ImageAxes_2);
            W = [0 1 0;1 -4 1; 0 1 0];
            A = padarray(A,[1,1]);
            IMG1 = A;
            I = zeros(size(A));
            A = double(A);
            for i = 2:size(A,1)-1
                for j = 2:size(A,2)-1
                    I(i,j) = sum(sum(W .* A(i-1:i+1,j-1:j+1)));       
                end
            end
            I = uint8(I);
            B = IMG1 - I;
            imh = imhandles(app.ImageAxes_2);
            set(imh, 'CData', B);
            updatergbaxes(app);
        end        
        
        function invertcolors(app, value)
            axes2 = get(app.ImageAxes_2, 'Children');
            W = get(axes2(1), 'CData');
            Wnew = 255-W; 
            imh = imhandles(app.ImageAxes_2);
            set(imh, 'CData', Wnew);
            updatergbaxes(app);
        end
        
        function hist_img = my_histeq(app, im, L)
            % Forming 0's of length L.
            c = zeros(255, 1);
            for i = 0:255 - 1
                c(i+1) = sum(sum(255 == i));
            end
            p = c / (size(im, 1) * 255);    % Size of image
            s = (L-1) * cumsum(p);  % Cumulative hist of each pixel 
            s = round(s);           % CDF Of each pixel
            hist_img = uint8(zeros(255));  % Convert size.
            for k=1:size(s,1)                   % Replace final value of each pixel
                hist_img(255 == k-1) = s(k) / 255;
            end
        end
        
        function noise(app, value)
            L = get(app.NoiseSlider, 'Value');
            my_image = getimage(app.ImageAxes_2);
            image_thresholded = my_image;
            a=0.05; b=0.05;
            X=rand(size(my_image,1),size(my_image,2));
            c= X<=a;
            image_thresholded(c)=0;
            u=a+b;
            c=find(X>a & X<=u);
            image_thresholded(c)=1;
            imh = imhandles(app.ImageAxes_2);
            set(imh, 'CData', image_thresholded);         
            updatergbaxes(app);
        end
        
        function updatergbaxes(app)
            im = getimage(app.ImageAxes_2);
            imagesc(app.ImageAxes_2,im);
            histr = histogram(app.RedAxes, im(:,:,1), 'FaceColor', [1 0 0], 'EdgeColor', 'none');
            histg = histogram(app.GreenAxes, im(:,:,2), 'FaceColor', [0 1 0], 'EdgeColor', 'none');
            histb = histogram(app.BlueAxes, im(:,:,3), 'FaceColor', [0 0 1], 'EdgeColor', 'none');
            maxr = max(histr.BinCounts);
            maxg = max(histg.BinCounts);
            maxb = max(histb.BinCounts);
            maxcount = max([maxr maxg maxb]);
            app.RedAxes.YLim = [0 maxcount];
            app.RedAxes.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
            app.GreenAxes.YLim = [0 maxcount];
            app.GreenAxes.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
            app.BlueAxes.YLim = [0 maxcount];
            app.BlueAxes.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
        end
        
        function adjustbrightness(app, value)
            L = get(app.BrightnessSlider, 'Value');
            im = getimage(app.ImageAxes_2);
            imb = im + L;
            imh = imhandles(app.ImageAxes_2);
            set(imh, 'CData', imb);
            updatergbaxes(app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Configure image axes
            app.ImageAxes.Visible = 'on';
            app.ImageAxes_2.Visible = 'on';
            app.ImageAxes.Colormap = gray(256);
            axis(app.ImageAxes, 'image');
            axis(app.ImageAxes_2, 'image');
        end

        % Callback function
        function DropDownValueChanged(app, event)
            
            % Update the image and histograms
            updateimage(app, app.DropDown.Value);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
               
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif; *.bmp','All Image Files'};
            [f, p] = uigetfile(filterspec);
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               fname = [p f];
               updateimage(app, fname);
            end
        end

        % Button pushed function: ExportImageButton
        function ExportImageButtonPushed(app, event)
            exportimage(app);
        end

        % Value changed function: ContrastSlider
        function ContrastSliderValueChanged(app, event)
            value = app.ContrastSlider.Value;
            applycontrast(app, value);
        end

        % Value changed function: GrayscaleSwitch
        function GrayscaleSwitchValueChanged(app, event)
            value = app.GrayscaleSwitch.Value;
            grayscale(app, value);
        end

        % Value changed function: InvertColorsSwitch
        function InvertColorsSwitchValueChanged(app, event)
            value = app.InvertColorsSwitch.Value;
            invertcolors(app, value);
        end

        % Value changed function: SharpeningSlider
        function SharpeningSliderValueChanged(app, event)
            value = app.SharpeningSlider.Value;
            sharpening(app, value);
        end

        % Value changed function: NoiseSlider
        function NoiseSliderValueChanged(app, event)
            value = app.NoiseSlider.Value;
            noise(app, value);
        end

        % Value changed function: BrightnessSlider
        function BrightnessSliderValueChanged(app, event)
            value = app.BrightnessSlider.Value;
            adjustbrightness(app, value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Image Histograms';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load Image';
            app.ImportedImageLabel = uilabel(app.UIFigure);
            app.ImportedImageLabel.Text = 'Imported Image';
            app.CurrentEditLabel = uilabel(app.UIFigure);
            app.CurrentEditLabel.Text = 'Current Edit';
            app.ExportImageButton = uibutton(app.UIFigure, 'push');
            app.ExportImageButton.ButtonPushedFcn = createCallbackFcn(app, @ExportImageButtonPushed, true);
            app.ExportImageButton.Text = 'Export Image';
            app.ContrastSliderLabel = uilabel(app.UIFigure);
            app.ContrastSliderLabel.Text = 'Contrast';
            app.ContrastSlider = uislider(app.UIFigure);
            app.ContrastSlider.ValueChangedFcn = createCallbackFcn(app, @ContrastSliderValueChanged, true);
            app.GrayscaleSwitchLabel = uilabel(app.UIFigure);
            app.GrayscaleSwitchLabel.Text = 'Grayscale';
            app.GrayscaleSwitch = uiswitch(app.UIFigure, 'slider');
            app.GrayscaleSwitch.ValueChangedFcn = createCallbackFcn(app, @GrayscaleSwitchValueChanged, true);
            app.NoiseSliderLabel = uilabel(app.UIFigure);
            app.NoiseSliderLabel.Text = 'Noise';
            app.NoiseSlider = uislider(app.UIFigure);
            app.NoiseSlider.ValueChangedFcn = createCallbackFcn(app, @NoiseSliderValueChanged, true);
            app.SharpeningSliderLabel = uilabel(app.UIFigure);
            app.SharpeningSliderLabel.Text = 'Sharpening';
            app.SharpeningSlider = uislider(app.UIFigure);
            app.SharpeningSlider.ValueChangedFcn = createCallbackFcn(app, @SharpeningSliderValueChanged, true);
            app.InvertColorsSwitchLabel = uilabel(app.UIFigure);
            app.InvertColorsSwitchLabel.Text = 'Invert Colors';
            app.InvertColorsSwitch = uiswitch(app.UIFigure, 'slider');
            app.InvertColorsSwitch.ValueChangedFcn = createCallbackFcn(app, @InvertColorsSwitchValueChanged, true);
            app.BrightnessSliderLabel = uilabel(app.UIFigure);
            app.BrightnessSliderLabel.Text = 'Brightness';
            app.BrightnessSlider = uislider(app.UIFigure);
            app.BrightnessSlider.ValueChangedFcn = createCallbackFcn(app, @BrightnessSliderValueChanged, true);
            app.ImageAxes = uiaxes(app.UIFigure);
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = {'[ ]'};
            app.ImageAxes.YTick = [];
            app.GreenAxes = uiaxes(app.UIFigure);
            title(app.GreenAxes, 'Green')
            xlabel(app.GreenAxes, 'Intensity')
            ylabel(app.GreenAxes, 'Pixels')
            app.GreenAxes.XLim = [0 255];
            app.GreenAxes.XTick = [0 128 255];
            app.BlueAxes = uiaxes(app.UIFigure);
            title(app.BlueAxes, 'Blue')
            xlabel(app.BlueAxes, 'Intensity')
            ylabel(app.BlueAxes, 'Pixels')
            app.BlueAxes.XLim = [0 255];
            app.BlueAxes.XTick = [0 128 255];
            app.RedAxes = uiaxes(app.UIFigure);
            title(app.RedAxes, 'Red')
            xlabel(app.RedAxes, 'Intensity')
            ylabel(app.RedAxes, 'Pixels')
            app.RedAxes.XLim = [0 255];
            app.RedAxes.XTick = [0 128 255];
            app.ImageAxes_2 = uiaxes(app.UIFigure);
            app.ImageAxes_2.XTick = [];
            app.ImageAxes_2.XTickLabel = {'[ ]'};
            app.ImageAxes_2.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Anderson_edit_FinalProject

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end